classdef opto_analyser_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ImageIOLabel                    matlab.ui.control.Label
        ReleasefileEditFieldLabel       matlab.ui.control.Label
        ReleasefileEditField            matlab.ui.control.EditField
        RecoveryfileEditFieldLabel      matlab.ui.control.Label
        RecoveryfileEditField           matlab.ui.control.EditField
        CellNameEditFieldLabel          matlab.ui.control.Label
        CellNameEditField               matlab.ui.control.EditField
        NoofintervalsLabel              matlab.ui.control.Label
        OKButton                        matlab.ui.control.StateButton
        ConfirmSelectionLabel           matlab.ui.control.Label
        OutputinformationLabel          matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        IntervalsselectedLabel          matlab.ui.control.Label
        RESETButton                     matlab.ui.control.StateButton
        CenterPanel                     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ChannelselectionTab             matlab.ui.container.Tab
        Channel1Label                   matlab.ui.control.Label
        FrameSliderLabel                matlab.ui.control.Label
        FrameSlider                     matlab.ui.control.Slider
        Channel1ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton_5                    matlab.ui.control.RadioButton
        MitochondriaButton              matlab.ui.control.RadioButton
        CytoplasmButton                 matlab.ui.control.RadioButton
        NucleusButton                   matlab.ui.control.RadioButton
        Channel2ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton_6                    matlab.ui.control.RadioButton
        MitochondriaButton_2            matlab.ui.control.RadioButton
        CytoplasmButton_2               matlab.ui.control.RadioButton
        NucleusButton_2                 matlab.ui.control.RadioButton
        Channel3ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton_7                    matlab.ui.control.RadioButton
        MitochondriaButton_3            matlab.ui.control.RadioButton
        CytoplasmButton_3               matlab.ui.control.RadioButton
        NucleusButton_3                 matlab.ui.control.RadioButton
        Channel4ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton_8                    matlab.ui.control.RadioButton
        MitochondriaButton_4            matlab.ui.control.RadioButton
        CytoplasmButton_4               matlab.ui.control.RadioButton
        NucleusButton_4                 matlab.ui.control.RadioButton
        AcceptChannelsandCropImageButton  matlab.ui.control.StateButton
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        AcceptCropButton                matlab.ui.control.StateButton
        Channel2Label                   matlab.ui.control.Label
        Channel3Label                   matlab.ui.control.Label
        Channel4Label                   matlab.ui.control.Label
        CompartmentviewingpercentilesTab  matlab.ui.container.Tab
        LoadNextIterationButton         matlab.ui.control.StateButton
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes7_2                       matlab.ui.control.UIAxes
        PercentageSliderLabel           matlab.ui.control.Label
        PercentageSlider                matlab.ui.control.Slider
        PercentageSlider_2Label         matlab.ui.control.Label
        PercentageSlider_2              matlab.ui.control.Slider
        NucleusLabel                    matlab.ui.control.Label
        MitochondriaLabel               matlab.ui.control.Label
        AcceptPercentilesforImageViewingButton  matlab.ui.control.StateButton
        FramewindowSliderLabel          matlab.ui.control.Label
        FramewindowSlider               matlab.ui.control.Slider
        FramewindowSlider_2Label        matlab.ui.control.Label
        FramewindowSlider_2             matlab.ui.control.Slider
        UIAxes7_3                       matlab.ui.control.UIAxes
        PercentageSlider_3Label         matlab.ui.control.Label
        PercentageSlider_3              matlab.ui.control.Slider
        FramewindowSlider_3Label        matlab.ui.control.Label
        FramewindowSlider_3             matlab.ui.control.Slider
        AllcompartmentsLabel            matlab.ui.control.Label
        CellboundarycropTab             matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes6_2                       matlab.ui.control.UIAxes
        UIAxes6_3                       matlab.ui.control.UIAxes
        ImageSelectionButtonGroup       matlab.ui.container.ButtonGroup
        NullButton_2                    matlab.ui.control.ToggleButton
        Image1Button                    matlab.ui.control.ToggleButton
        Image2Button                    matlab.ui.control.ToggleButton
        Image3Button                    matlab.ui.control.ToggleButton
        Image4Button                    matlab.ui.control.ToggleButton
        ConfirmcropButton               matlab.ui.control.StateButton
        UIAxes6_4                       matlab.ui.control.UIAxes
        AllframesLabel                  matlab.ui.control.Label
        RegionframesLabel               matlab.ui.control.Label
        MaxintensityallchannelsLabel    matlab.ui.control.Label
        MaxintensityallchannelsLabel_2  matlab.ui.control.Label
        MaxintensityproteinchannelLabel  matlab.ui.control.Label
        MaxintensityproteinchannelLabel_2  matlab.ui.control.Label
        RoughcropButton                 matlab.ui.control.Button
        CellthresholdingTab             matlab.ui.container.Tab
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes8_2                       matlab.ui.control.UIAxes
        UIAxes8_3                       matlab.ui.control.UIAxes
        Thresholdscale01SliderLabel     matlab.ui.control.Label
        Thresholdscale01Slider          matlab.ui.control.Slider
        Thresholdscale001SliderLabel    matlab.ui.control.Label
        Thresholdscale001Slider         matlab.ui.control.Slider
        Thresholdscale0001SliderLabel   matlab.ui.control.Label
        Thresholdscale0001Slider        matlab.ui.control.Slider
        Thresholdscale00001SliderLabel  matlab.ui.control.Label
        Thresholdscale00001Slider       matlab.ui.control.Slider
        Thresholdvalue000000Label       matlab.ui.control.Label
        AcceptthresholdButton           matlab.ui.control.Button
        CompartmentthresholdingTab      matlab.ui.container.Tab
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes10_2                      matlab.ui.control.UIAxes
        UIAxes10_3                      matlab.ui.control.UIAxes
        UIAxes10_4                      matlab.ui.control.UIAxes
        UIAxes10_5                      matlab.ui.control.UIAxes
        UIAxes10_6                      matlab.ui.control.UIAxes
        Mitochondriathresholdscale01SliderLabel  matlab.ui.control.Label
        Mitochondriathresholdscale01Slider  matlab.ui.control.Slider
        Mitochondriathresholdscale001SliderLabel  matlab.ui.control.Label
        Mitochondriathresholdscale001Slider  matlab.ui.control.Slider
        Mitochondriathresholdscale0001SliderLabel  matlab.ui.control.Label
        Mitochondriathresholdscale0001Slider  matlab.ui.control.Slider
        Mitochondriathresholdscale00001SliderLabel  matlab.ui.control.Label
        Mitochondriathresholdscale00001Slider  matlab.ui.control.Slider
        Nucleusthresholdscale01SliderLabel  matlab.ui.control.Label
        Nucleusthresholdscale01Slider   matlab.ui.control.Slider
        Nucleusthresholdscale001SliderLabel  matlab.ui.control.Label
        Nucleusthresholdscale001Slider  matlab.ui.control.Slider
        Nucleusthresholdscale0001SliderLabel  matlab.ui.control.Label
        Nucleusthresholdscale0001Slider  matlab.ui.control.Slider
        Nucleusthresholdscale00001SliderLabel  matlab.ui.control.Label
        Nucleusthresholdscale00001Slider  matlab.ui.control.Slider
        Mitochondrialthresholdvalue000000Label  matlab.ui.control.Label
        AcceptthresholdsButton          matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        lsm_flag = 1 % flags whether files are both of lsm type (=1) or not (=0).
        error_counter = 0;
        intervals = [];
        
        LSMRel
        LSMRec
        TRel
        TRec
        total_frames
        channels
        u
        v
        interval_stage
        interval_counter
        
        nuclear_channel
        mitochondrial_channel
        protein_channel
       
        ImgCRelAll
        ImgCRecAll
        ImgCAll
        IntensityAll
        CellRel
      
        
        vertstart
        vertend
        horzstart
        horzend
        
        app_stage=0 % Only allows app functionality to be implemented in the correct order
        
        ImgNRel
        ImgCRel
        ImgMRel
        
        ImgNRec
        ImgCRec
        ImgMRec
        
        zoom_crop_flag = 0;
        rough_crop_flag = 0;
        
        AllCellRel
        AllCellRec
        AllCellRelMax
        AllCellRecMax
        AllCellMax
        AllCellC1
        AllCell
        
        ImgN
        ImgC1
        ImgM
        
        regions
        Midpoints
        
        AllCellRegionMax
        RegionNMax
        RegionMMax
        RegionC1Max
        MedianRegion
        
        all_cell_prctile
        nuclear_prctile
        mito_prctile
        
        all_cell_region_index
        nuclear_region_index
        mito_region_index
        
        all_cell_slider_prctile_flag
        nuclear_slider_prctile_flag
        mito_slider_prctile_flag
        
        all_cell_interval_image
        nuc_interval_image
        mito_interval_image
        
        RegionRemoveRegion
        Sectionbw
        mito_bw
        nuc_bw
        
        whole_cell_threshold
        mito_cell_threshold
        nuc_cell_threshold
        
        cropping_stage = 0
    end
    
    methods (Access = private)
        
        function plot_imagesc(app,UIAxes,input_image)
            title(UIAxes, []);
            xlabel(UIAxes, []);
            ylabel(UIAxes, []);
            UIAxes.XAxis.TickLabels = {};
            UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imagesc(input_image, 'Parent', UIAxes, ...
                'XData', [1 UIAxes.Position(3)], ...
                'YData', [1 UIAxes.Position(4)]);
            % Set limits of axes
            UIAxes.XLim = [0 I.XData(2)];
            UIAxes.YLim = [0 I.YData(2)];
            axis(UIAxes, 'equal');
            
        end
        
        function plot_imshow(app,UIAxes,input_image)
            title(UIAxes, []);
            xlabel(UIAxes, []);
            ylabel(UIAxes, []);
            UIAxes.XAxis.TickLabels = {};
            UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow(input_image, 'Parent', UIAxes, ...
                'XData', [1 UIAxes.Position(3)], ...
                'YData', [1 UIAxes.Position(4)]);
            % Set limits of axes
            UIAxes.XLim = [0 I.XData(2)];
            UIAxes.YLim = [0 I.YData(2)];
            axis(UIAxes, 'equal');
            
        end


        function stage_error_counter = stage_error(app,current_stage,required_stage,cropping_stage)
            stage_error_counter = 0;
            if cropping_stage == 1
                info_label = 'Error Messages';
                lsm_type_error = 'Error. Complete square crop in new image window.';
                stage_error_counter = 1;
                info_label = [info_label newline '(1)' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            elseif cropping_stage == 2
                info_label = 'Error Messages';
                lsm_type_error = 'Error. Complete rough crop around cell in new image window.';
                stage_error_counter = 1;
                info_label = [info_label newline '(1)' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
                app.OutputinformationLabel.BackgroundColor = 'k';
            else
                if current_stage == 0 && required_stage ~= 0
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete I/O correctly.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                elseif current_stage == 1 && required_stage ~= 1
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Complete channel allocation and select' newline '"Accept Channels and Crop Image" in' newline 'Channel selection tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                elseif current_stage == 2 && required_stage ~= 2
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select "Accept crop" in Channel selection tab.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                elseif current_stage == 3 && required_stage ~= 3
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select "Load next iteration" in Compartment viewing percentiles tab.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                elseif current_stage == 4 && required_stage ~= 4
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select percentiles for each compartment in Compartment viewing percentiles tab.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                elseif current_stage == 5 && required_stage ~= 5
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select "Accept Percentiles for Image Viewing" in Compartment viewing percentiles tab.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end      
        
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RESETButton
        function RESETButtonValueChanged(app, event)
            %value = app.RESETButton.Value;
            %startupFcn(app);
            %Add reset here. Look up how to do.
        end

        % Value changed function: OKButton
        function OKButtonValueChanged(app, event)
            %value = app.OKButton.Value;
        
            stage_error_counter = stage_error(app,app.app_stage,0,app.cropping_stage);
            
            if stage_error_counter == 0
                app.lsm_flag = 1;
                app.OutputinformationLabel.BackgroundColor = 'none';
                app.error_counter = 0;
                app.OutputinformationLabel.Text = 'Error Messages';
                info_label = app.OutputinformationLabel.Text;
                release_file=app.ReleasefileEditField.Value;
                recovery_file=app.RecoveryfileEditField.Value;
                if (isempty(release_file)) || (isempty(release_file)) || (length(release_file) < 3) || (length(recovery_file) < 3)
                   lsm_type_error = ['Error. Release and recovery' newline 'filename(s) cannot be empty.' newline 'There may be additional errors.'];
                   app.lsm_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.OutputinformationLabel.Text = info_label;
                   app.OutputinformationLabel.BackgroundColor = 'k';
                   return
                end
                if(strcmp(release_file(end-2:end),'lsm')==0)||(strcmp(recovery_file(end-2:end),'lsm')==0)
                   lsm_type_error = ['Error. Image files must' newline 'be of lsm type.'];
                   app.lsm_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.OutputinformationLabel.Text = info_label;
                   app.OutputinformationLabel.BackgroundColor = 'k';
                end
                
                if (isfile(release_file) == 0) || (isfile(recovery_file) == 0)
                    lsm_type_error = ['Error. At least one image' newline 'file does not exist.'];
                    app.lsm_flag = 0;
                    app.error_counter = app.error_counter +1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end
                if (isempty(app.CellNameEditField.Value))
                   lsm_type_error = ['Error. Cell Name cannot be empty.' newline 'There may be additional errors.'];
                   app.lsm_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.OutputinformationLabel.Text = info_label;
                   app.OutputinformationLabel.BackgroundColor = 'k';
                   return
                end
                if (isempty(regexp(app.CellNameEditField.Value,'\W', 'once')) == 0)
                    lsm_type_error = ['Error. Cell name cannot include' newline 'spaces or special characters.'];
                    app.lsm_flag = 0;
                    app.error_counter = app.error_counter +1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end
               
               interval_string = app.TextArea.Value;
               interval_string = interval_string{1}; 
               if (isempty(interval_string)) 
                   lsm_type_error = 'Error. No. of intervals cannot be empty.';
                   app.lsm_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.OutputinformationLabel.Text = info_label;
                   app.OutputinformationLabel.BackgroundColor = 'k';
                   return
                end
                app.IntervalsselectedLabel.Text = 'Intervals selected: ';
                app.intervals = [];
                print_intervals=1;
                value_split=strsplit(interval_string,',');
                for I=1:length(value_split)
                    value_num=str2double(value_split{I});
                    if(isempty(value_num))
                        lsm_type_error = ['Error. Intervals must be integers' newline 'greater than zero in' newline 'comma separated format.'];
                        app.lsm_flag = 0;
                        app.error_counter = app.error_counter +1;
                        info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.OutputinformationLabel.Text = info_label;
                        app.OutputinformationLabel.BackgroundColor = 'k';
                        print_intervals=0;
                        break
                    elseif (mod(value_num,1) ~= 0) ||  (value_num < 0)
                        lsm_type_error = ['Error. Intervals must be integers' newline 'greater than zero in' newline 'comma separated format.'];
                        app.lsm_flag = 0;
                        app.error_counter = app.error_counter +1;
                        info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.OutputinformationLabel.Text = info_label;
                        app.OutputinformationLabel.BackgroundColor = 'k';
                        print_intervals=0;
                        break
                    else
                        app.intervals = [app.intervals,value_num];    
                    end
                end
                if(isempty(app.intervals) == 0) && (print_intervals == 1)
                    app.intervals=unique(app.intervals);
                    integer_string = sprintf('%.0f,' , app.intervals);
                    integer_string = integer_string(1:end-1);% strip final comma
                    app.IntervalsselectedLabel.Text=[app.IntervalsselectedLabel.Text newline integer_string];
                end
                if (app.lsm_flag ==1)
                    %Add code to find lsmread
                    [app.LSMRel, ~] = lsmread(app.ReleasefileEditField.Value);
                    [app.TRel,app.channels,~,app.u,app.v]=size(app.LSMRel);
                    
                    [app.LSMRec, ~] = lsmread(app.RecoveryfileEditField.Value);
                    [app.TRec,~,~,u_rec,v_rec]=size(app.LSMRec);
                    
                    app.total_frames = app.TRel + app.TRec;
    
            
    %                 app.interval_stage=0;
    %                 app.interval_counter=0;
                    %[time, app.channel, dim, row, col] = size(app.LSMRel);
                    
                    if app.channels~=3 && app.channels~=4
                        lsm_type_error = 'Error. No. of channels must be 3 or 4.';
                        app.lsm_flag = 0;
                        app.error_counter = app.error_counter +1;
                        info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.OutputinformationLabel.Text = info_label;
                        app.OutputinformationLabel.BackgroundColor = 'k';
                    elseif u_rec ~= app.u || v_rec ~= app.v
                        lsm_type_error = ['Error. Spatial dimensions of' newline 'release and recovery phases' newline 'should be the same.'];
                        app.lsm_flag = 0;
                        app.error_counter = app.error_counter +1;
                        info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.OutputinformationLabel.Text = info_label;
                        app.OutputinformationLabel.BackgroundColor = 'k';
                    else
                        ch1(:, :) = app.LSMRel(1, 1, 1, :, :);
                        ch2(:, :) = app.LSMRel(1, 2, 1, :, :);
                        ch3(:, :) = app.LSMRel(1, 3, 1, :, :);
                        
                        plot_imagesc(app,app.UIAxes,ch1)
                        plot_imagesc(app,app.UIAxes2,ch2)
                        plot_imagesc(app,app.UIAxes3,ch3)
    
                               
                        if app.channels == 4
                            ch4(:, :) = app.LSMRel(1, 4, 1, :, :);
                            plot_imagesc(app,app.UIAxes4,ch4)
    
                        end
                        
                        app.app_stage=1;
                        app.OutputinformationLabel.BackgroundColor = 'none';
                    end
                end
            end
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = uint8(min(max(round(event.Value),1),app.TRel));
            stage_error_counter = stage_error(app,app.app_stage,1,app.cropping_stage);
            if stage_error_counter == 0

                app.OutputinformationLabel.BackgroundColor = 'none';
                ch1(:, :) = app.LSMRel(changingValue, 1, 1, :, :);
                ch2(:, :) = app.LSMRel(changingValue, 2, 1, :, :);
                ch3(:, :) = app.LSMRel(changingValue, 3, 1, :, :);
                
                plot_imagesc(app,app.UIAxes,ch1)
                plot_imagesc(app,app.UIAxes2,ch2)
                plot_imagesc(app,app.UIAxes3,ch3)

                       
                if app.channels == 4
                    ch4(:, :) = app.LSMRel(changingValue, 4, 1, :, :);
                    plot_imagesc(app,app.UIAxes4,ch4)

                end
                   
            end
            
        end

        % Value changed function: AcceptChannelsandCropImageButton
        function AcceptChannelsandCropImageButtonValueChanged(app, event)
            %value = app.AcceptChannelsandCropImageButton.Value;
            stage_error_counter = stage_error(app,app.app_stage,1,app.cropping_stage);
            if stage_error_counter == 0
                app.lsm_flag = 1;
                app.OutputinformationLabel.BackgroundColor = 'none';
                app.error_counter = 0;
                app.OutputinformationLabel.Text = 'Error Messages';
                info_label = app.OutputinformationLabel.Text;
                if (app.NoneButton_8.Value == 0) && (app.channels == 3) 
                   lsm_type_error = ['Error. There are only three channels.' newline 'Channel 4 should be "None".'];
                   app.lsm_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.OutputinformationLabel.Text = info_label;
                   app.OutputinformationLabel.BackgroundColor = 'k';
                end
                
                total_nucleus_radio = app.NucleusButton.Value + app.NucleusButton_2.Value + app.NucleusButton_3.Value + app.NucleusButton_4.Value;
                total_mitochondria_radio = app.MitochondriaButton.Value + app.MitochondriaButton_2.Value + app.MitochondriaButton_3.Value + app.MitochondriaButton_4.Value;
                total_cytoplasm_radio = app.CytoplasmButton.Value + app.CytoplasmButton_2.Value + app.CytoplasmButton_3.Value + app.CytoplasmButton_4.Value;
                
                if total_cytoplasm_radio == 2
                   lsm_type_error = ['Error. The app can analyse only one protein' newline 'at a time at present'];
                   app.lsm_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.OutputinformationLabel.Text = info_label;
                   app.OutputinformationLabel.BackgroundColor = 'k';
                end
                
                
                if total_nucleus_radio ~= 1 || total_mitochondria_radio ~= 1 || (total_cytoplasm_radio ~= 1 && total_cytoplasm_radio ~= 2)
                   lsm_type_error = ['Error. There must be one allocated' newline 'protein channel, nuclear channel and' newline 'mitochondrial channel.'];
                   app.lsm_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.OutputinformationLabel.Text = info_label;
                   app.OutputinformationLabel.BackgroundColor = 'k';
                end
                
                if app.lsm_flag == 1
                    nucleus_location = [app.NucleusButton.Value, app.NucleusButton_2.Value, app.NucleusButton_3.Value, app.NucleusButton_4.Value];
                    protein_location = [app.CytoplasmButton.Value, app.CytoplasmButton_2.Value, app.CytoplasmButton_3.Value, app.CytoplasmButton_4.Value];
                    mitochondria_location = [app.MitochondriaButton.Value, app.MitochondriaButton_2.Value, app.MitochondriaButton_3.Value, app.MitochondriaButton_4.Value];
                    app.nuclear_channel = find(nucleus_location);
                    app.mitochondrial_channel = find(mitochondria_location);
                    app.protein_channel = find(protein_location);
                    
                    app.ImgCRelAll=zeros(app.u,app.v,app.TRel);
                    for I = 1:app.TRel
                        app.ImgCRelAll(:, :, I)=app.LSMRel(I, app.protein_channel, 1, :, :);
                    end
                    app.ImgCRecAll=zeros(app.u,app.v,app.TRec);
                    for I = 1:app.TRec
                        app.ImgCRecAll(:, :, I)=app.LSMRec(I, app.protein_channel, 1, :, :);
                    end
                    app.ImgCAll = cat(3, app.ImgCRelAll, app.ImgCRecAll);
                    app.IntensityAll = sum(sum(app.ImgCAll,1), 2);
                    app.CellRel(1:app.u, 1:app.v) = app.LSMRel(1,app.protein_channel,1,:,:);
                    
                    image_input = app.CellRel;
                    image_input=double(image_input-min(image_input(:)));
                    image_input=image_input./max(image_input(:));
                    image_input=uint16(round((2^16-1)*image_input));
                    rgbcell = ind2rgb(image_input, parula(2^16));
                                        
                    crop_image = figure;
                    imshow(rgbcell);
                    iptsetpref('ImshowAxesVisible','off')
                    axis('equal');
                    app.cropping_stage = 1;
                    app.zoom_crop_flag = 1;
                    %Draw a rectangle around image to get new image bounds
                    h = drawrectangle;
                    BW = createMask(h);
                    delete(h);
                    delete(crop_image);
                    index = find(BW);
                    [y, x] = ind2sub(size(BW),index);

                    max_x=max(x);
                    min_x=min(x);
                    max_y=max(y);
                    min_y=min(y);
                    y_length = max_y - min_y +1;
                    x_length = max_x - min_x +1;
                    [height,width]= size(BW);
                    if max(y_length,x_length) < min(height, width)
                        xy_diff = abs(y_length - x_length);
                        side1=floor(xy_diff/2)+xy_diff-2*floor(xy_diff/2);
                        side2=floor(xy_diff/2);
                        if x_length < y_length
                           upper_x = min(max_x+side1,width);
                           lower_x = max(min_x-side2,1);
                           if max_x+side1 > width
                               lower_x = lower_x  - (upper_x - width);
                           end
                           if min_x-side2 < 1
                               upper_x = upper_x + (1-min_x+side2);
                           end
                           lower_y=min_y;
                           upper_y=max_y;
                        elseif y_length < x_length
                            upper_y = min(max_y+side1,height);
                            lower_y = max(min_y-side2,1);
                            if max_y+side1 > height
                                lower_y = lower_y  - (upper_y - height);
                            end
                            if min_y-side2 < 1
                                upper_y = upper_y + (1-min_y+side2);
                            end
                            lower_x=min_x;
                            upper_x=max_x;
                         else
                            lower_y=min_y;
                            upper_y=max_y;
                            lower_x=min_x;
                            upper_x=max_y;
                        end
                        max_x = upper_x;
                        min_x = lower_x;
                        max_y = upper_y;
                        min_y = lower_y;

                    end

                    app.vertstart = double(min_y);
                    app.vertend = double(max_y);
                    app.horzstart = double(min_x);
                    app.horzend = double(max_x);
                    
                    %app.app_stage = 2;
                    app.cropping_stage = 0;
                    app.zoom_crop_flag = 1;
                    app.OutputinformationLabel.BackgroundColor = 'none';
                end

            end

        end

        % Value changed function: AcceptCropButton
        function AcceptCropButtonValueChanged(app, event)
            %value = app.AcceptCropButton.Value;
            stage_error_counter = stage_error(app,app.app_stage,1,app.cropping_stage);
            if stage_error_counter == 0
                if app.zoom_crop_flag ==0
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select "Accept crop" in Channel selection tab.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                else
                    app.OutputinformationLabel.BackgroundColor = 'none';
                    for I=1:app.TRel
                        app.ImgNRel(:,:,I) = app.LSMRel(I,app.nuclear_channel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                        app.ImgCRel(:,:,I) = app.LSMRel(I,app.protein_channel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                        app.ImgMRel(:,:,I) = app.LSMRel(I,app.mitochondrial_channel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                    end
                    for I=1:app.TRec
                        app.ImgNRec(:,:,I) = app.LSMRec(I,app.nuclear_channel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                        app.ImgCRec(:,:,I) = app.LSMRec(I,app.protein_channel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                        app.ImgMRec(:,:,I) = app.LSMRec(I,app.mitochondrial_channel,1,app.vertstart:app.vertend,app.horzstart:app.horzend);
                    end

                    %Maximum of all release compartments (3d for time)
                    app.AllCellRel = max(max(app.ImgNRel,app.ImgCRel),app.ImgMRel);
                    %Maximum of all recovery compartments (3d for time)
                    app.AllCellRec = max(max(app.ImgNRec,app.ImgCRec),app.ImgMRec);
                    %2D maximum  of all release stages
                    app.AllCellRelMax = max(app.AllCellRel,[],3);
                    %2D maximum of all recovery stages
                    app.AllCellRecMax = max(app.AllCellRec,[],3);
                    %2D maximum of entire release and recovery movies
                    app.AllCellMax = max(app.AllCellRelMax,app.AllCellRecMax);
                    %Maximum of all compartment for every release and recovery
                    %frame
                    app.AllCell=cat(3,app.AllCellRel,app.AllCellRec);
                    app.AllCellC1 = max(max(app.ImgCRel,[],3),max(app.ImgCRec,[],3));
                    
                    app.ImgN=cat(3,app.ImgNRel,app.ImgNRec);
                    app.ImgC1=cat(3,app.ImgCRel,app.ImgCRec);
                    app.ImgM=cat(3,app.ImgMRel,app.ImgMRec);
                    
                    app.app_stage =2;
                    app.interval_stage = 1;
                    app.interval_counter = 1;
                    
                    app.TabGroup.SelectedTab = app.CompartmentviewingpercentilesTab;
                end

             end
        end

        % Value changed function: LoadNextIterationButton
        function LoadNextIterationButtonValueChanged(app, event)
            %value = app.LoadNextIterationButton.Value;
            stage_error_counter = stage_error(app,app.app_stage,2,app.cropping_stage);
            if stage_error_counter == 0
                app.OutputinformationLabel.BackgroundColor = 'none';
                if app.interval_counter == 1
                    app.all_cell_slider_prctile_flag = 0;
                    app.nuclear_slider_prctile_flag = 0;
                    app.mito_slider_prctile_flag = 0;
                    app.regions = 1:(app.total_frames-1)/app.intervals(app.interval_stage):app.total_frames;%Start point of each region. 
                    app.regions = round(app.regions);
                    app.Midpoints=1+(app.total_frames-1)/app.intervals(app.interval_stage)/2:(app.total_frames-1)/app.intervals(app.interval_stage):app.total_frames;
                    app.Midpoints=round(app.Midpoints);
               
                    app.AllCellRegionMax=zeros([size(app.AllCellMax),app.intervals(app.interval_stage)]);
                    app.RegionNMax=zeros([size(app.AllCellMax),app.intervals(app.interval_stage)]);
                    app.RegionMMax=zeros([size(app.AllCellMax),app.intervals(app.interval_stage)]);
                    app.RegionC1Max=zeros([size(app.AllCellMax),app.intervals(app.interval_stage)]);
                    app.MedianRegion=zeros([size(app.AllCellMax),app.intervals(app.interval_stage)]);
               
                    for J=1:app.intervals(app.interval_stage)
                        if J == app.intervals(app.interval_stage)
                            end_pt = app.regions(J+1);
                        else
                            end_pt = app.regions(J+1)-1;
                        end
                        %Median intensity for maximum intensities over time
                        app.MedianRegion(:,:,J)=uint16(median(max(max(app.ImgN(:,:,app.regions(J):end_pt),app.ImgC1(:,:,app.regions(J):end_pt)),app.ImgM(:,:,app.regions(J):end_pt)),3));
                        %Maximum intensity of nucleus in that region
                        app.RegionNMax(:,:,J)=max(app.ImgN(:,:,app.regions(J):end_pt),[],3);
                        %Maximum intesnity of cytoplasm in that region
                        app.RegionC1Max(:,:,J)=max(app.ImgC1(:,:,app.regions(J):end_pt),[],3);
                        %Maximum intensity of mitochondria in that region
                        app.RegionMMax(:,:,J)=max(app.ImgM(:,:,app.regions(J):end_pt),[],3);
                        %Maximum intensity of entire cell over that time region
                        app.AllCellRegionMax(:,:,J)=max(app.RegionMMax(:,:,J),max(app.RegionNMax(:,:,J),app.RegionC1Max(:,:,J)));
%                         
                    end

                    %app.MedianRegionInput=app.MedianRegion;
                    %app.InputImgCRegion=app.RegionC1Max;
                    %app.InputImgNRegion=app.RegionNMax;
                    
                    app.all_cell_prctile=0;
                    app.nuclear_prctile=0;
                    app.mito_prctile=0;
                    app.all_cell_region_index=min(1,length(app.regions)-1);
                    app.nuclear_region_index=min(1,length(app.regions)-1);
                    app.mito_region_index=min(1,length(app.regions)-1);
                    all_cell_image_percentile=uint16(prctile(app.AllCell(:,:,app.regions(app.all_cell_region_index):app.regions(app.all_cell_region_index+1)-1),app.all_cell_prctile,3));
                    nuc_image_percentile=uint16(prctile(app.ImgN(:,:,app.regions(app.nuclear_region_index):app.regions(app.nuclear_region_index+1)-1),app.nuclear_prctile,3));
                    mito_image_percentile=uint16(prctile(app.ImgM(:,:,app.regions(app.mito_region_index):app.regions(app.mito_region_index+1)-1),app.mito_prctile,3));
                   
                    plot_imagesc(app,app.UIAxes7_3,all_cell_image_percentile)
                    plot_imagesc(app,app.UIAxes7,nuc_image_percentile)
                    plot_imagesc(app,app.UIAxes7_2,mito_image_percentile)

                    app.app_stage = 3;
                    
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end

            end               

%                 %This needs putting somewhere else now
%                 if app.interval_counter == app.intervals(app.interval_stage)
%                     app.interval_stage = app.interval_stage + 1;
%                     app.interval_counter = 1;
%                 else
%                     app.interval_counter = app.interval_counter +1;
%                 end
%                 
%                 if app.interval_counter == 1
%                    
%                 end

        end

        % Value changing function: FramewindowSlider_3
        function FramewindowSlider_3ValueChanging(app, event)
            changingValue = uint8(event.Value);
            stage_error_counter = stage_error(app,app.app_stage,3,app.cropping_stage);
            if stage_error_counter == 0
                app.OutputinformationLabel.BackgroundColor = 'none';
                if app.interval_counter == 1

                    app.all_cell_region_index=min(changingValue,length(app.regions)-1);
                    all_cell_image_percentile=uint16(prctile(app.AllCell(:,:,app.regions(app.all_cell_region_index):app.regions(app.all_cell_region_index+1)-1),app.all_cell_prctile,3));
                    
                    plot_imagesc(app,app.UIAxes7_3,all_cell_image_percentile)
   
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end
 
            end  
            
        end

        % Value changing function: FramewindowSlider
        function FramewindowSliderValueChanging(app, event)
            changingValue = uint8(event.Value);
            stage_error_counter = stage_error(app,app.app_stage,3,app.cropping_stage);
            if stage_error_counter == 0
                app.OutputinformationLabel.BackgroundColor = 'none';
                if app.interval_counter == 1

                    app.nuclear_region_index=min(changingValue,length(app.regions)-1);
                    nuc_image_percentile=uint16(prctile(app.ImgN(:,:,app.regions(app.nuclear_region_index):app.regions(app.nuclear_region_index+1)-1),app.nuclear_prctile,3));
                    
                    plot_imagesc(app,app.UIAxes7,nuc_image_percentile)
                    
                    
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end

            end  
     
        end

        % Value changing function: FramewindowSlider_2
        function FramewindowSlider_2ValueChanging(app, event)
            changingValue = uint8(event.Value);
            stage_error_counter = stage_error(app,app.app_stage,3,app.cropping_stage);
            if stage_error_counter == 0
                app.OutputinformationLabel.BackgroundColor = 'none';
                if app.interval_counter == 1
                    app.mito_region_index=min(changingValue,length(app.regions)-1);
                    mito_image_percentile=uint16(prctile(app.ImgM(:,:,app.regions(app.mito_region_index):app.regions(app.mito_region_index+1)-1),app.mito_prctile,3));
                    
                    plot_imagesc(app,app.UIAxes7_2,mito_image_percentile)

                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end

            end              
            
        end

        % Value changing function: PercentageSlider_3
        function PercentageSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            stage_error_counter = stage_error(app,app.app_stage,3,app.cropping_stage);
            if stage_error_counter == 0
                app.OutputinformationLabel.BackgroundColor = 'none';
                if app.interval_counter == 1
                    app.all_cell_slider_prctile_flag = 1;
                                       
                    app.all_cell_prctile=changingValue;
            
                    all_cell_image_percentile=uint16(prctile(app.AllCell(:,:,app.regions(app.all_cell_region_index):app.regions(app.all_cell_region_index+1)-1),app.all_cell_prctile,3));
                    plot_imagesc(app,app.UIAxes7_3,all_cell_image_percentile)
          

                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end
            end       
        end

        % Value changing function: PercentageSlider
        function PercentageSliderValueChanging(app, event)
            changingValue = event.Value;
            stage_error_counter = stage_error(app,app.app_stage,3,app.cropping_stage);
            if stage_error_counter == 0
                app.OutputinformationLabel.BackgroundColor = 'none';
                if app.interval_counter == 1
                    app.nuclear_slider_prctile_flag = 1;
                                       
                    app.nuclear_prctile=changingValue;
            
                    nuc_image_percentile=uint16(prctile(app.ImgN(:,:,app.regions(app.nuclear_region_index):app.regions(app.nuclear_region_index+1)-1),app.nuclear_prctile,3));
                    
              
                    plot_imagesc(app,app.UIAxes7,nuc_image_percentile)
                    

                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end
            end                       
        end

        % Value changing function: PercentageSlider_2
        function PercentageSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            stage_error_counter = stage_error(app,app.app_stage,3,app.cropping_stage);
            if stage_error_counter == 0
                app.OutputinformationLabel.BackgroundColor = 'none';
                if app.interval_counter == 1
                    app.mito_slider_prctile_flag = 1;
                    app.mito_prctile = changingValue;
                    mito_image_percentile = uint16(prctile(app.ImgM(:,:,app.regions(app.mito_region_index):app.regions(app.mito_region_index+1)-1),app.mito_prctile,3));
                    plot_imagesc(app,app.UIAxes7_2,mito_image_percentile)
               else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end
            end     
        end

        % Value changed function: 
        % AcceptPercentilesforImageViewingButton
        function AcceptPercentilesforImageViewingButtonValueChanged(app, event)
            %value = app.AcceptPercentilesforImageViewingButton.Value;
            %proceed_to_cropping_flag = 1;
            app.error_counter = 0;
            info_label = 'Error Messages';
            stage_error_counter = stage_error(app,app.app_stage,3,app.cropping_stage);
            if stage_error_counter == 0
                if app.interval_counter ~= 1
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = app.error_counter+ 1;
                    %proceed_to_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end
            
                if app.nuclear_slider_prctile_flag ~= 1
                    lsm_type_error = 'Error. Select nuclear imaging percentile using the slider.';
                    app.error_counter = app.error_counter+ 1;
                    %proceed_to_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end 
                
                if app.mito_slider_prctile_flag ~= 1
                    lsm_type_error = 'Error. Select mitochondrial imaging percentile using the slider.';
                    app.error_counter = app.error_counter+ 1;
                    %proceed_to_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end
                
                if app.interval_counter == 1 && app.nuclear_slider_prctile_flag == 1 && app.mito_slider_prctile_flag == 1 && app.all_cell_slider_prctile_flag == 1
                    
 
                    if app.intervals(app.interval_stage) ==1
                        image_input1 = zeros(size(app.AllCellMax));
                        image_input1(:,:) = app.AllCellMax;
                        image_input2 = zeros(size(app.AllCellMax));
                        image_input2(:,:) = app.AllCellC1;
                    else
                        image_input1 = zeros(size(app.AllCellMax));
                        image_input1(:,:) = app.AllCellRegionMax(:,:,app.interval_counter);
                        
                        image_input2 = zeros(size(app.AllCellMax));
                        image_input2(:,:) = app.RegionC1Max(:,:,app.interval_counter);
                    end
                    
                    plot_imagesc(app,app.UIAxes6,image_input1)
                    plot_imagesc(app,app.UIAxes6_2,image_input2)
                    plot_imagesc(app,app.UIAxes6_3,app.AllCellMax)
                    plot_imagesc(app,app.UIAxes6_4,app.AllCellC1)

                        
                    app.all_cell_interval_image=zeros([size(app.AllCellMax),app.intervals(app.interval_stage)]);
                    app.nuc_interval_image=zeros([size(app.AllCellMax),app.intervals(app.interval_stage)]);
                    app.mito_interval_image=zeros([size(app.AllCellMax),app.intervals(app.interval_stage)]);
                    for J=1:app.intervals(app.interval_stage)
                        if J == app.intervals(app.interval_stage)
                            end_pt = app.regions(J+1);
                        else
                            end_pt = app.regions(J+1)-1;
                        end
                        app.all_cell_interval_image(:,:,J) = uint16(prctile(app.AllCell(:,:,app.regions(J):end_pt),app.all_cell_prctile,3));
                        app.nuc_interval_image(:,:,J) = uint16(prctile(app.ImgN(:,:,app.regions(J):end_pt),app.nuclear_prctile,3));
                        app.mito_interval_image(:,:,J) = uint16(prctile(app.ImgM(:,:,app.regions(J):end_pt),app.mito_prctile,3));
                    end
                    
                    app.app_stage = 4;
                    app.rough_crop_flag = 0;
                    app.TabGroup.SelectedTab = app.CellboundarycropTab;
                end

            end
            
        end

        % Selection changed function: ImageSelectionButtonGroup
        function ImageSelectionButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ImageSelectionButtonGroup.SelectedObject;
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0

                if app.Image1Button.Value == true

                    if app.intervals(app.interval_stage) ==1
                        image_input = zeros(size(app.AllCellMax));
                        image_input(:,:) = app.AllCellMax;
                    else
                        image_input = zeros(size(app.AllCellMax));
                        image_input(:,:) = app.AllCellRegionMax(:,:,app.interval_counter);
                    end
                    plot_imagesc(app,app.UIAxes5,image_input)
  
                elseif app.Image2Button.Value == true
                    
                    if app.intervals(app.interval_stage) ==1
                        image_input = zeros(size(app.AllCellMax));
                        image_input(:,:) = app.AllCellC1;
                    else
                        image_input = zeros(size(app.AllCellMax));
                        image_input(:,:) = app.RegionC1Max(:,:,app.interval_counter);
                    end
                    plot_imagesc(app,app.UIAxes5,image_input)
                    
                elseif app.Image3Button.Value == true
                    plot_imagesc(app,app.UIAxes5,app.AllCellMax)
                    
                elseif app.Image4Button.Value == true
                    plot_imagesc(app,app.UIAxes5,app.AllCellC1)
                else
                    cla(app.UIAxes5)
                end
            end
        end

        % Button pushed function: RoughcropButton
        function RoughcropButtonPushed(app, event)
            %Place errors here for incorrectly timed press
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
            
                if app.NullButton_2 == true
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select best image for cropping cell outline.';
                    app.error_counter = app.error_counter+ 1;
                    %proceed_to_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                else
                    if app.Image1Button.Value == true
                        if app.intervals(app.interval_stage) ==1
                            image_input = zeros(size(app.AllCellMax));
                            image_input(:,:) = app.AllCellMax;
                        else
                            image_input = zeros(size(app.AllCellMax));
                            image_input(:,:) = app.AllCellRegionMax(:,:,app.interval_counter);
                        end                
                    elseif app.Image2Button.Value == true
                        if app.intervals(app.interval_stage) ==1
                            image_input = zeros(size(app.AllCellMax));
                            image_input(:,:) = app.AllCellC1;
                        else
                            image_input = zeros(size(app.AllCellMax));
                            image_input(:,:) = app.RegionC1Max(:,:,app.interval_counter);
                        end
                    elseif app.Image3Button.Value == true
                        image_input = zeros(size(app.AllCellMax));
                        image_input(:,:) =  app.AllCellMax;
                    elseif app.Image4Button.Value == true
                        image_input = zeros(size(app.AllCellMax));
                        image_input(:,:) =  app.AllCellC1;
                    end
                    image_input=double(image_input-min(image_input(:)));
                    image_input=image_input./max(image_input(:));
                    image_input=uint16(round((2^16-1)*image_input));
                    rgbcell = ind2rgb(image_input, parula(2^16));
                                        
                   app.cropping_stage = 2; 
                    
                    rough_crop_image = figure;
                    imshow(rgbcell);
                    iptsetpref('ImshowAxesVisible','off')
                    axis('equal');
                    %imagesc(image_input);
                    iptsetpref('ImshowAxesVisible','off')
                    
                    %axis('equal');
                    %Draw a rectangle around image to get new image bounds
                    app.RegionRemoveRegion(:,:,app.interval_counter)=roipoly;
                    delete(rough_crop_image);
                    app.rough_crop_flag = 1;
                    app.cropping_stage = 0;
                end
            end
            
            
        end

        % Value changed function: ConfirmcropButton
        function ConfirmcropButtonValueChanged(app, event)
            %value = app.ConfirmcropButton.Value;
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                if app.rough_crop_flag == 1
                    %Add error checking
                    app.app_stage = 5;
                    app.whole_cell_threshold = 0;
                   
                    image_input = zeros(size(app.AllCellMax));
                    image_input(:,:) = app.all_cell_interval_image(:,:,app.interval_counter);
                    plot_imagesc(app,app.UIAxes8,image_input)
        
                    all_cell_interval_image_bw=imbinarize(uint16(image_input),0);
                    all_cell_interval_image_bw = all_cell_interval_image_bw.*app.RegionRemoveRegion(:,:,app.interval_counter);
                    all_cell_interval_image_bw=imfill(all_cell_interval_image_bw,'holes');
                    SE = strel('disk', 1, 0);
                    all_cell_interval_image_bw=imopen(all_cell_interval_image_bw,SE);
                    L = bwlabel(all_cell_interval_image_bw);
                    LNo=length(unique(L(:)))-1;
                    if(LNo>0)
                        clear SizeI
                        SizeI=zeros(LNo);
                        for J=1:LNo
                            index=find(L==J);
                            SizeI(J)=length(index);
                        end
                        all_cell_interval_image_bw=bwareaopen(all_cell_interval_image_bw,max(SizeI),8);
                    end
                    app.Sectionbw(:,:,app.interval_counter)=all_cell_interval_image_bw;
                    
                    plot_imshow(app,app.UIAxes8_2,all_cell_interval_image_bw)
        
                    
                    all_cell_interval_image_bw=bwmorph(all_cell_interval_image_bw,'remove');
                    
                    image_input=double(image_input-min(image_input(:)));
                    image_input=image_input./max(image_input(:));
                    image_input=uint16(round((2^16-1)*image_input));
                    rgbcell = ind2rgb(image_input, parula(2^16));
                    index=find(all_cell_interval_image_bw);
                    channel=rgbcell(:,:,1);
                    channel(index)=2^16-1;
                    rgbcell(:,:,1)=channel;
                    channel=rgbcell(:,:,2);
                    channel(index)=2^16-1;
                    rgbcell(:,:,2)=channel;
                    channel=rgbcell(:,:,3);
                    channel(index)=2^16-1;
                    rgbcell(:,:,3)=channel;
                    
                    plot_imshow(app,app.UIAxes8_3,rgbcell)
        
                    app.TabGroup.SelectedTab = app.CellthresholdingTab;
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select Rough Crop to outline cell.';
                    app.error_counter = app.error_counter+ 1;
                    %proceed_to_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    app.OutputinformationLabel.BackgroundColor = 'k';
                end%Finished here
                
            end
        end

        % Value changed function: Thresholdscale01Slider
        function Thresholdscale01SliderValueChanged(app, event)
            value = app.Thresholdscale01Slider.Value;
            %changingValue = event.Value;
            
            %value = event.Value;
            % determine which discrete option the current value is closest to.
            [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
            [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            if min_major < min_minor
               event.Source.Value = event.Source.MajorTicks(minIdx_major);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MajorTicks(minIdx_major); 
            else
               event.Source.Value = event.Source.MinorTicks(minIdx_minor);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MinorTicks(minIdx_minor);                 
            end
            changingValue = value;            
            current_value = app.whole_cell_threshold;

            app.whole_cell_threshold = changingValue + (current_value*10-fix(current_value*10))/10;
            
            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.all_cell_interval_image(:,:,app.interval_counter);
          
            all_cell_interval_image_bw=imbinarize(uint16(image_input),app.whole_cell_threshold);
            all_cell_interval_image_bw = all_cell_interval_image_bw.*app.RegionRemoveRegion(:,:,app.interval_counter);
            all_cell_interval_image_bw=imfill(all_cell_interval_image_bw,'holes');
            SE = strel('disk', 1, 0);
            all_cell_interval_image_bw=imopen(all_cell_interval_image_bw,SE);
            L = bwlabel(all_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                clear SizeI
                SizeI=zeros(LNo);
                for J=1:LNo
                    index=find(L==J);
                    SizeI(J)=length(index);
                end
                all_cell_interval_image_bw=bwareaopen(all_cell_interval_image_bw,max(SizeI),8);
            end
            app.Sectionbw(:,:,app.interval_counter)=all_cell_interval_image_bw;
            
            plot_imshow(app,app.UIAxes8_2,all_cell_interval_image_bw)

            all_cell_interval_image_bw=bwmorph(all_cell_interval_image_bw,'remove');
            
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(all_cell_interval_image_bw);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            
            plot_imshow(app,app.UIAxes8_3,rgbcell)

            
            threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
            app.Thresholdvalue000000Label.Text = threshold_label;
        end

        % Value changed function: Thresholdscale001Slider
        function Thresholdscale001SliderValueChanged(app, event)
            value = app.Thresholdscale001Slider.Value;
            %value = event.Value;
            
            % determine which discrete option the current value is closest to.
            [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
            [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            if min_major < min_minor
               event.Source.Value = event.Source.MajorTicks(minIdx_major);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MajorTicks(minIdx_major); 
            else
               event.Source.Value = event.Source.MinorTicks(minIdx_minor);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MinorTicks(minIdx_minor);                 
            end
            changingValue = value; 
            current_value = app.whole_cell_threshold;
            
            app.whole_cell_threshold = changingValue + current_value - floor(100*(10*current_value-fix(10*current_value))/10)/100;


            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.all_cell_interval_image(:,:,app.interval_counter);
          
            all_cell_interval_image_bw=imbinarize(uint16(image_input),app.whole_cell_threshold);
            all_cell_interval_image_bw = all_cell_interval_image_bw.*app.RegionRemoveRegion(:,:,app.interval_counter);
            all_cell_interval_image_bw=imfill(all_cell_interval_image_bw,'holes');
            SE = strel('disk', 1, 0);
            all_cell_interval_image_bw=imopen(all_cell_interval_image_bw,SE);
            L = bwlabel(all_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                SizeI=zeros(LNo);
                for J=1:LNo
                    index=find(L==J);
                    SizeI(J)=length(index);
                end
                all_cell_interval_image_bw=bwareaopen(all_cell_interval_image_bw,max(SizeI),8);
            end
            app.Sectionbw(:,:,app.interval_counter)=all_cell_interval_image_bw;
            plot_imshow(app,app.UIAxes8_2,all_cell_interval_image_bw)

            all_cell_interval_image_bw=bwmorph(all_cell_interval_image_bw,'remove');
            
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(all_cell_interval_image_bw);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            plot_imshow(app,app.UIAxes8_3,rgbcell)

            
%            threshold_label = 'Threshold value:';
            threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
            app.Thresholdvalue000000Label.Text = threshold_label;
        end

        % Value changed function: Thresholdscale0001Slider
        function Thresholdscale0001SliderValueChanged(app, event)
            value = app.Thresholdscale0001Slider.Value;
            %value = event.Value;
            % determine which discrete option the current value is closest to.
            [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
            [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            if min_major < min_minor
               event.Source.Value = event.Source.MajorTicks(minIdx_major);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MajorTicks(minIdx_major); 
            else
               event.Source.Value = event.Source.MinorTicks(minIdx_minor);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MinorTicks(minIdx_minor);                 
            end
            changingValue = value;            
            current_value = app.whole_cell_threshold;
            app.whole_cell_threshold = changingValue + current_value - floor(1000*(100*current_value-fix(100*current_value))/100)/1000;
            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.all_cell_interval_image(:,:,app.interval_counter);
          
            all_cell_interval_image_bw=imbinarize(uint16(image_input),app.whole_cell_threshold);
            all_cell_interval_image_bw = all_cell_interval_image_bw.*app.RegionRemoveRegion(:,:,app.interval_counter);
            all_cell_interval_image_bw=imfill(all_cell_interval_image_bw,'holes');
            SE = strel('disk', 1, 0);
            all_cell_interval_image_bw=imopen(all_cell_interval_image_bw,SE);
            L = bwlabel(all_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                SizeI=zeros(LNo);
                for J=1:LNo
                    index=find(L==J);
                    SizeI(J)=length(index);
                end
                all_cell_interval_image_bw=bwareaopen(all_cell_interval_image_bw,max(SizeI),8);
            end
            app.Sectionbw(:,:,app.interval_counter)=all_cell_interval_image_bw;
            plot_imshow(app,app.UIAxes8_2,all_cell_interval_image_bw)

            all_cell_interval_image_bw=bwmorph(all_cell_interval_image_bw,'remove');
            
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(all_cell_interval_image_bw);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            plot_imshow(app,app.UIAxes8_3,rgbcell)


            threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
            app.Thresholdvalue000000Label.Text = threshold_label;            
        end

        % Value changed function: Thresholdscale00001Slider
        function Thresholdscale00001SliderValueChanged(app, event)
            value = app.Thresholdscale00001Slider.Value;
            %value = event.Value;
            % determine which discrete option the current value is closest to.
            [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
            [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            if min_major < min_minor
               event.Source.Value = event.Source.MajorTicks(minIdx_major);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MajorTicks(minIdx_major); 
            else
               event.Source.Value = event.Source.MinorTicks(minIdx_minor);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MinorTicks(minIdx_minor);                 
            end
            changingValue = value;            
            current_value = app.whole_cell_threshold;
            app.whole_cell_threshold = changingValue + current_value - floor(10000*(1000*current_value-fix(1000*current_value))/1000)/10000;
            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.all_cell_interval_image(:,:,app.interval_counter);
          
            all_cell_interval_image_bw=imbinarize(uint16(image_input),app.whole_cell_threshold);
            all_cell_interval_image_bw = all_cell_interval_image_bw.*app.RegionRemoveRegion(:,:,app.interval_counter);
            all_cell_interval_image_bw=imfill(all_cell_interval_image_bw,'holes');
            SE = strel('disk', 1, 0);
            all_cell_interval_image_bw=imopen(all_cell_interval_image_bw,SE);
            L = bwlabel(all_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                SizeI = zeros(LNo);
                for J=1:LNo
                    index=find(L==J);
                    SizeI(J)=length(index);
                end
                all_cell_interval_image_bw=bwareaopen(all_cell_interval_image_bw,max(SizeI),8);
            end
            app.Sectionbw(:,:,app.interval_counter)=all_cell_interval_image_bw;
            plot_imshow(app,app.UIAxes8_2,all_cell_interval_image_bw)

            all_cell_interval_image_bw=bwmorph(all_cell_interval_image_bw,'remove');
            
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(all_cell_interval_image_bw);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            plot_imshow(app,app.UIAxes8_3,rgbcell)


            threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
            app.Thresholdvalue000000Label.Text = threshold_label;
        end

        % Button pushed function: AcceptthresholdButton
        function AcceptthresholdButtonPushed(app, event)
            app.app_stage = 7;
            
            app.mito_cell_threshold = 0;
            app.nuc_cell_threshold = 0;
%             nuc_interval_image
%                      mito_interval_image
           
            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.mito_interval_image(:,:,app.interval_counter);
            
            plot_imagesc(app,app.UIAxes10,image_input)
  
            mito_cell_interval_image_bw = imbinarize(uint16(image_input),app.mito_cell_threshold);
            mito_cell_interval_image_bw=app.Sectionbw(:,:,app.interval_counter).*mito_cell_interval_image_bw;
            app.mito_bw(:,:,app.interval_counter)=mito_cell_interval_image_bw;
            
            plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)

            mito_cell_interval_image_skel=bwmorph(mito_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(mito_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            plot_imshow(app,app.UIAxes10_3,rgbcell)
          
            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.nuc_interval_image(:,:,app.interval_counter);
            plot_imagesc(app,app.UIAxes10_4,image_input)

            nuc_cell_interval_image_bw = imbinarize(uint16(image_input),app.nuc_cell_threshold);
            nuc_cell_interval_image_bw=app.Sectionbw(:,:,app.interval_counter).*nuc_cell_interval_image_bw;
            nuc_cell_interval_image_bw=imfill(nuc_cell_interval_image_bw,'holes');
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if LNo>0
                nuc_object_area = zeros(LNo);
                for J=1:LNo
                    index_area=find(L==J);
                    nuc_object_area(J)=length(index_area);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            
            mito_cell_interval_image_bw = zeros(size(app.AllCellMax));
            mito_cell_interval_image_bw(:,:) = app.mito_bw(:,:,app.interval_counter);
            overlap_bw=nuc_cell_interval_image_bw.*mito_cell_interval_image_bw;
            %index_overlap=find(overlap_bw==1);
            nuc_cell_interval_image_bw(overlap_bw==1)=0;
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                for J=1:LNo
                    index=find(L==J);
                    nuc_object_area(J)=length(index);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            app.nuc_bw(:,:,app.interval_counter)=nuc_cell_interval_image_bw;
            plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)

            nuc_cell_interval_image_skel=bwmorph(nuc_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(nuc_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            plot_imshow(app,app.UIAxes10_6,rgbcell)

            app.TabGroup.SelectedTab = app.CompartmentthresholdingTab;
        end

        % Value changed function: Mitochondriathresholdscale01Slider
        function Mitochondriathresholdscale01SliderValueChanged(app, event)
            value = app.Mitochondriathresholdscale01Slider.Value;
            
            
            %value = event.Value;
            % determine which discrete option the current value is closest to.
            [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
            [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            if min_major < min_minor
               event.Source.Value = event.Source.MajorTicks(minIdx_major);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MajorTicks(minIdx_major); 
            else
               event.Source.Value = event.Source.MinorTicks(minIdx_minor);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MinorTicks(minIdx_minor);                 
            end
            changingValue = value;            
            current_value = app.mito_cell_threshold;

            app.mito_cell_threshold = changingValue + (current_value*10-fix(current_value*10))/10;
            
            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.mito_interval_image(:,:,app.interval_counter);
          

            mito_cell_interval_image_bw = imbinarize(uint16(image_input),app.mito_cell_threshold);
            mito_cell_interval_image_bw=app.Sectionbw(:,:,app.interval_counter).*mito_cell_interval_image_bw;
            app.mito_bw(:,:,app.interval_counter)=mito_cell_interval_image_bw;

            plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)

            mito_cell_interval_image_skel=bwmorph(mito_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(mito_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            
            plot_imshow(app,app.UIAxes10_3,rgbcell)

            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.nuc_interval_image(:,:,app.interval_counter);
            
            nuc_cell_interval_image_bw = imbinarize(uint16(image_input),app.nuc_cell_threshold);
            nuc_cell_interval_image_bw=app.Sectionbw(:,:,app.interval_counter).*nuc_cell_interval_image_bw;
            nuc_cell_interval_image_bw=imfill(nuc_cell_interval_image_bw,'holes');
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if LNo>0
                nuc_object_area = zeros(LNo);
                for J=1:LNo
                    index_area=find(L==J);
                    nuc_object_area(J)=length(index_area);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            
            mito_cell_interval_image_bw = zeros(size(app.AllCellMax));
            mito_cell_interval_image_bw(:,:) = app.mito_bw(:,:,app.interval_counter);
            overlap_bw=nuc_cell_interval_image_bw.*mito_cell_interval_image_bw;
            %index_overlap=find(overlap_bw==1);
            nuc_cell_interval_image_bw(overlap_bw==1)=0;
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                for J=1:LNo
                    index=find(L==J);
                    nuc_object_area(J)=length(index);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            app.nuc_bw(:,:,app.interval_counter)=nuc_cell_interval_image_bw;
            
            plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)

            nuc_cell_interval_image_skel=bwmorph(nuc_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(nuc_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            plot_imshow(app,app.UIAxes10_6,rgbcell)

            
            threshold_label = ['Mitochondrial threshold value: '  num2str(app.mito_cell_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nuc_cell_threshold,'%.5f')];
            app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
            
            
        end

        % Value changed function: 
        % Mitochondriathresholdscale001Slider
        function Mitochondriathresholdscale001SliderValueChanged(app, event)
            value = app.Mitochondriathresholdscale001Slider.Value;
            
            %value = event.Value;
            % determine which discrete option the current value is closest to.
            [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
            [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            if min_major < min_minor
               event.Source.Value = event.Source.MajorTicks(minIdx_major);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MajorTicks(minIdx_major); 
            else
               event.Source.Value = event.Source.MinorTicks(minIdx_minor);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MinorTicks(minIdx_minor);                 
            end
            changingValue = value; 
            current_value = app.mito_cell_threshold;
            
            app.mito_cell_threshold = changingValue + current_value - floor(100*(10*current_value-fix(10*current_value))/10)/100;

           
            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.mito_interval_image(:,:,app.interval_counter);
          
                        
            mito_cell_interval_image_bw = imbinarize(uint16(image_input),app.mito_cell_threshold);
            mito_cell_interval_image_bw=app.Sectionbw(:,:,app.interval_counter).*mito_cell_interval_image_bw;
            app.mito_bw(:,:,app.interval_counter)=mito_cell_interval_image_bw;
            plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)

            mito_cell_interval_image_skel=bwmorph(mito_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(mito_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            plot_imshow(app,app.UIAxes10_3,rgbcell)

            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.nuc_interval_image(:,:,app.interval_counter);
            
            nuc_cell_interval_image_bw = imbinarize(uint16(image_input),app.nuc_cell_threshold);
            nuc_cell_interval_image_bw=app.Sectionbw(:,:,app.interval_counter).*nuc_cell_interval_image_bw;
            nuc_cell_interval_image_bw=imfill(nuc_cell_interval_image_bw,'holes');
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if LNo>0
                nuc_object_area = zeros(LNo);
                for J=1:LNo
                    index_area=find(L==J);
                    nuc_object_area(J)=length(index_area);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            
            mito_cell_interval_image_bw = zeros(size(app.AllCellMax));
            mito_cell_interval_image_bw(:,:) = app.mito_bw(:,:,app.interval_counter);
            overlap_bw=nuc_cell_interval_image_bw.*mito_cell_interval_image_bw;
            %index_overlap=find(overlap_bw==1);
            nuc_cell_interval_image_bw(overlap_bw==1)=0;
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                nuc_object_area = zeros(LNo);
                for J=1:LNo
                    index=find(L==J);
                    nuc_object_area(J)=length(index);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            app.nuc_bw(:,:,app.interval_counter)=nuc_cell_interval_image_bw;
            plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)

            nuc_cell_interval_image_skel=bwmorph(nuc_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(nuc_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            plot_imshow(app,app.UIAxes10_6,rgbcell)

            
            threshold_label = ['Mitochondrial threshold value: '  num2str(app.mito_cell_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nuc_cell_threshold,'%.5f')];
            app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
            
        end

        % Value changed function: 
        % Mitochondriathresholdscale0001Slider
        function Mitochondriathresholdscale0001SliderValueChanged(app, event)
            value = app.Mitochondriathresholdscale0001Slider.Value;
            %value = event.Value;
            % determine which discrete option the current value is closest to.
            [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
            [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            if min_major < min_minor
               event.Source.Value = event.Source.MajorTicks(minIdx_major);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MajorTicks(minIdx_major); 
            else
               event.Source.Value = event.Source.MinorTicks(minIdx_minor);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MinorTicks(minIdx_minor);                 
            end
            changingValue = value; 
            current_value = app.mito_cell_threshold;
            app.mito_cell_threshold = changingValue + current_value - floor(1000*(100*current_value-fix(100*current_value))/100)/1000;
           
            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.mito_interval_image(:,:,app.interval_counter);
          
                       
            mito_cell_interval_image_bw = imbinarize(uint16(image_input),app.mito_cell_threshold);
            mito_cell_interval_image_bw=app.Sectionbw(:,:,app.interval_counter).*mito_cell_interval_image_bw;
            app.mito_bw(:,:,app.interval_counter)=mito_cell_interval_image_bw;
            
            plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)

            mito_cell_interval_image_skel=bwmorph(mito_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(mito_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            
            plot_imshow(app,app.UIAxes10_3,rgbcell)

            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.nuc_interval_image(:,:,app.interval_counter);
            
            nuc_cell_interval_image_bw = imbinarize(uint16(image_input),app.nuc_cell_threshold);
            nuc_cell_interval_image_bw=app.Sectionbw(:,:,app.interval_counter).*nuc_cell_interval_image_bw;
            nuc_cell_interval_image_bw=imfill(nuc_cell_interval_image_bw,'holes');
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if LNo>0
                nuc_object_area = zeros(LNo);
                for J=1:LNo
                    index_area=find(L==J);
                    nuc_object_area(J)=length(index_area);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            
            mito_cell_interval_image_bw = zeros(size(app.AllCellMax));
            mito_cell_interval_image_bw(:,:) = app.mito_bw(:,:,app.interval_counter);
            overlap_bw=nuc_cell_interval_image_bw.*mito_cell_interval_image_bw;
            %index_overlap=find(overlap_bw==1);
            nuc_cell_interval_image_bw(overlap_bw==1)=0;
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                for J=1:LNo
                    index=find(L==J);
                    nuc_object_area(J)=length(index);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            app.nuc_bw(:,:,app.interval_counter)=nuc_cell_interval_image_bw;
            
            plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)

            nuc_cell_interval_image_skel=bwmorph(nuc_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(nuc_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            plot_imshow(app,app.UIAxes10_6,rgbcell)

            
            threshold_label = ['Mitochondrial threshold value: '  num2str(app.mito_cell_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nuc_cell_threshold,'%.5f')];
            app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
            
            
        end

        % Value changed function: 
        % Mitochondriathresholdscale00001Slider
        function Mitochondriathresholdscale00001SliderValueChanged(app, event)
            value = app.Mitochondriathresholdscale00001Slider.Value;
            %value = event.Value;
            % determine which discrete option the current value is closest to.
            [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
            [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            if min_major < min_minor
               event.Source.Value = event.Source.MajorTicks(minIdx_major);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MajorTicks(minIdx_major); 
            else
               event.Source.Value = event.Source.MinorTicks(minIdx_minor);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MinorTicks(minIdx_minor);                 
            end
            changingValue = value; 
            current_value = app.mito_cell_threshold;
            
            app.mito_cell_threshold = changingValue + current_value - floor(10000*(1000*current_value-fix(1000*current_value))/1000)/10000;
           
            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.mito_interval_image(:,:,app.interval_counter);


            mito_cell_interval_image_bw = imbinarize(uint16(image_input),app.mito_cell_threshold);
            mito_cell_interval_image_bw=app.Sectionbw(:,:,app.interval_counter).*mito_cell_interval_image_bw;
            app.mito_bw(:,:,app.interval_counter)=mito_cell_interval_image_bw;
            
            plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)

            mito_cell_interval_image_skel=bwmorph(mito_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(mito_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            plot_imshow(app,app.UIAxes10_3,rgbcell)

            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.nuc_interval_image(:,:,app.interval_counter);
            
            nuc_cell_interval_image_bw = imbinarize(uint16(image_input),app.nuc_cell_threshold);
            nuc_cell_interval_image_bw=app.Sectionbw(:,:,app.interval_counter).*nuc_cell_interval_image_bw;
            nuc_cell_interval_image_bw=imfill(nuc_cell_interval_image_bw,'holes');
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if LNo>0
                nuc_object_area = zeros(LNo);
                for J=1:LNo
                    index_area=find(L==J);
                    nuc_object_area(J)=length(index_area);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            
            mito_cell_interval_image_bw = zeros(size(app.AllCellMax));
            mito_cell_interval_image_bw(:,:) = app.mito_bw(:,:,app.interval_counter);
            overlap_bw=nuc_cell_interval_image_bw.*mito_cell_interval_image_bw;
            %index_overlap=find(overlap_bw==1);
            nuc_cell_interval_image_bw(overlap_bw==1)=0;
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                for J=1:LNo
                    index=find(L==J);
                    nuc_object_area(J)=length(index);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            app.nuc_bw(:,:,app.interval_counter)=nuc_cell_interval_image_bw;
            
            plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)

            nuc_cell_interval_image_skel=bwmorph(nuc_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(nuc_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            plot_imshow(app,app.UIAxes10_6,rgbcell)

            
            threshold_label = ['Mitochondrial threshold value: '  num2str(app.mito_cell_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nuc_cell_threshold,'%.5f')];
            app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
            
            
        end

        % Value changed function: Nucleusthresholdscale01Slider
        function Nucleusthresholdscale01SliderValueChanged(app, event)
            value = app.Nucleusthresholdscale01Slider.Value;
            
            
            %value = event.Value;
            % determine which discrete option the current value is closest to.
            [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
            [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            if min_major < min_minor
               event.Source.Value = event.Source.MajorTicks(minIdx_major);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MajorTicks(minIdx_major); 
            else
               event.Source.Value = event.Source.MinorTicks(minIdx_minor);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MinorTicks(minIdx_minor);                 
            end
            changingValue = value;            
            current_value = app.nuc_cell_threshold;

            app.nuc_cell_threshold = changingValue + (current_value*10-fix(current_value*10))/10;
            
            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.nuc_interval_image(:,:,app.interval_counter);
          
            mito_cell_interval_image_bw = zeros(size(app.AllCellMax));
            mito_cell_interval_image_bw(:,:) = app.mito_bw(:,:,app.interval_counter);


            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.nuc_interval_image(:,:,app.interval_counter);
            
            nuc_cell_interval_image_bw = imbinarize(uint16(image_input),app.nuc_cell_threshold);
            nuc_cell_interval_image_bw=app.Sectionbw(:,:,app.interval_counter).*nuc_cell_interval_image_bw;
            nuc_cell_interval_image_bw=imfill(nuc_cell_interval_image_bw,'holes');
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if LNo>0
                nuc_object_area = zeros(LNo);
                for J=1:LNo
                    index_area=find(L==J);
                    nuc_object_area(J)=length(index_area);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            
            overlap_bw=nuc_cell_interval_image_bw.*mito_cell_interval_image_bw;
            %index_overlap=find(overlap_bw==1);
            nuc_cell_interval_image_bw(overlap_bw==1)=0;
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                for J=1:LNo
                    index=find(L==J);
                    nuc_object_area(J)=length(index);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            app.nuc_bw(:,:,app.interval_counter)=nuc_cell_interval_image_bw;
            
            plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)

            nuc_cell_interval_image_skel=bwmorph(nuc_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(nuc_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            plot_imshow(app,app.UIAxes10_6,rgbcell)
            
            threshold_label = ['Mitochondrial threshold value: '  num2str(app.mito_cell_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nuc_cell_threshold,'%.5f')];
            app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;


        end

        % Value changed function: Nucleusthresholdscale001Slider
        function Nucleusthresholdscale001SliderValueChanged(app, event)
            value = app.Nucleusthresholdscale001Slider.Value;


            %value = event.Value;
            % determine which discrete option the current value is closest to.
            [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
            [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            if min_major < min_minor
               event.Source.Value = event.Source.MajorTicks(minIdx_major);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MajorTicks(minIdx_major); 
            else
               event.Source.Value = event.Source.MinorTicks(minIdx_minor);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MinorTicks(minIdx_minor);                 
            end
            changingValue = value;            
            current_value = app.nuc_cell_threshold;
                        
            app.nuc_cell_threshold = changingValue + current_value - floor(100*(10*current_value-fix(10*current_value))/10)/100;
            
            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.nuc_interval_image(:,:,app.interval_counter);
          
            mito_cell_interval_image_bw = zeros(size(app.AllCellMax));
            mito_cell_interval_image_bw(:,:) = app.mito_bw(:,:,app.interval_counter);


            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.nuc_interval_image(:,:,app.interval_counter);
            
            nuc_cell_interval_image_bw = imbinarize(uint16(image_input),app.nuc_cell_threshold);
            nuc_cell_interval_image_bw=app.Sectionbw(:,:,app.interval_counter).*nuc_cell_interval_image_bw;
            nuc_cell_interval_image_bw=imfill(nuc_cell_interval_image_bw,'holes');
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if LNo>0
                nuc_object_area = zeros(LNo);
                for J=1:LNo
                    index_area=find(L==J);
                    nuc_object_area(J)=length(index_area);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            
            overlap_bw=nuc_cell_interval_image_bw.*mito_cell_interval_image_bw;
            %index_overlap=find(overlap_bw==1);
            nuc_cell_interval_image_bw(overlap_bw==1)=0;
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                for J=1:LNo
                    index=find(L==J);
                    nuc_object_area(J)=length(index);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            app.nuc_bw(:,:,app.interval_counter)=nuc_cell_interval_image_bw;
            plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)

            nuc_cell_interval_image_skel=bwmorph(nuc_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(nuc_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            plot_imshow(app,app.UIAxes10_6,rgbcell)
            
            threshold_label = ['Mitochondrial threshold value: '  num2str(app.mito_cell_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nuc_cell_threshold,'%.5f')];
            app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
            
        end

        % Value changed function: Nucleusthresholdscale0001Slider
        function Nucleusthresholdscale0001SliderValueChanged(app, event)
            value = app.Nucleusthresholdscale0001Slider.Value;


            %value = event.Value;
            % determine which discrete option the current value is closest to.
            [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
            [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            if min_major < min_minor
               event.Source.Value = event.Source.MajorTicks(minIdx_major);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MajorTicks(minIdx_major); 
            else
               event.Source.Value = event.Source.MinorTicks(minIdx_minor);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MinorTicks(minIdx_minor);                 
            end
            changingValue = value;            
            current_value = app.nuc_cell_threshold;
            app.nuc_cell_threshold = changingValue + current_value - floor(1000*(100*current_value-fix(100*current_value))/100)/1000;

            
            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.nuc_interval_image(:,:,app.interval_counter);
          
            mito_cell_interval_image_bw = zeros(size(app.AllCellMax));
            mito_cell_interval_image_bw(:,:) = app.mito_bw(:,:,app.interval_counter);


            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.nuc_interval_image(:,:,app.interval_counter);
            
            nuc_cell_interval_image_bw = imbinarize(uint16(image_input),app.nuc_cell_threshold);
            nuc_cell_interval_image_bw=app.Sectionbw(:,:,app.interval_counter).*nuc_cell_interval_image_bw;
            nuc_cell_interval_image_bw=imfill(nuc_cell_interval_image_bw,'holes');
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if LNo>0
                nuc_object_area = zeros(LNo);
                for J=1:LNo
                    index_area=find(L==J);
                    nuc_object_area(J)=length(index_area);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            
            overlap_bw=nuc_cell_interval_image_bw.*mito_cell_interval_image_bw;
            %index_overlap=find(overlap_bw==1);
            nuc_cell_interval_image_bw(overlap_bw==1)=0;
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                for J=1:LNo
                    index=find(L==J);
                    nuc_object_area(J)=length(index);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            app.nuc_bw(:,:,app.interval_counter)=nuc_cell_interval_image_bw;
            
            plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)

            nuc_cell_interval_image_skel=bwmorph(nuc_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(nuc_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            plot_imshow(app,app.UIAxes10_6,rgbcell)
            
            threshold_label = ['Mitochondrial threshold value: '  num2str(app.mito_cell_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nuc_cell_threshold,'%.5f')];
            app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
            
        end

        % Value changed function: Nucleusthresholdscale00001Slider
        function Nucleusthresholdscale00001SliderValueChanged(app, event)
            value = app.Nucleusthresholdscale00001Slider.Value;


            %value = event.Value;
            % determine which discrete option the current value is closest to.
            [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
            [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            if min_major < min_minor
               event.Source.Value = event.Source.MajorTicks(minIdx_major);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MajorTicks(minIdx_major); 
            else
               event.Source.Value = event.Source.MinorTicks(minIdx_minor);
               % Override the selected value if you plan on using it within this function
               value = event.Source.MinorTicks(minIdx_minor);                 
            end
            changingValue = value;         
            current_value = app.nuc_cell_threshold;
            app.nuc_cell_threshold = changingValue + current_value - floor(10000*(1000*current_value-fix(1000*current_value))/1000)/10000;
             
            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.nuc_interval_image(:,:,app.interval_counter);
          
            mito_cell_interval_image_bw = zeros(size(app.AllCellMax));
            mito_cell_interval_image_bw(:,:) = app.mito_bw(:,:,app.interval_counter);


            image_input = zeros(size(app.AllCellMax));
            image_input(:,:) = app.nuc_interval_image(:,:,app.interval_counter);
            
            nuc_cell_interval_image_bw = imbinarize(uint16(image_input),app.nuc_cell_threshold);
            nuc_cell_interval_image_bw=app.Sectionbw(:,:,app.interval_counter).*nuc_cell_interval_image_bw;
            nuc_cell_interval_image_bw=imfill(nuc_cell_interval_image_bw,'holes');
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if LNo>0
                nuc_object_area = zeros(LNo);
                for J=1:LNo
                    index_area=find(L==J);
                    nuc_object_area(J)=length(index_area);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            
            overlap_bw=nuc_cell_interval_image_bw.*mito_cell_interval_image_bw;
            %index_overlap=find(overlap_bw==1);
            nuc_cell_interval_image_bw(overlap_bw==1)=0;
            clear nuc_object_area
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                for J=1:LNo
                    index=find(L==J);
                    nuc_object_area(J)=length(index);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            app.nuc_bw(:,:,app.interval_counter)=nuc_cell_interval_image_bw;
            
            plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
            nuc_cell_interval_image_skel=bwmorph(nuc_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(nuc_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            plot_imshow(app,app.UIAxes10_6,rgbcell)
            
            threshold_label = ['Mitochondrial threshold value: '  num2str(app.mito_cell_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nuc_cell_threshold,'%.5f')];
            app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImageIOLabel = uilabel(app.LeftPanel);
            app.ImageIOLabel.Text = 'Image I/O';
            app.ReleasefileEditFieldLabel = uilabel(app.LeftPanel);
            app.ReleasefileEditFieldLabel.Text = 'Release file';
            app.ReleasefileEditField = uieditfield(app.LeftPanel, 'text');
            app.ReleasefileEditField.Value = 'C:\Documents\release.lsm';
            app.RecoveryfileEditFieldLabel = uilabel(app.LeftPanel);
            app.RecoveryfileEditFieldLabel.Text = 'Recovery file';
            app.RecoveryfileEditField = uieditfield(app.LeftPanel, 'text');
            app.RecoveryfileEditField.Value = 'C:\Documents\recovery.lsm';
            app.CellNameEditFieldLabel = uilabel(app.LeftPanel);
            app.CellNameEditFieldLabel.Text = 'Cell Name';
            app.CellNameEditField = uieditfield(app.LeftPanel, 'text');
            app.CellNameEditField.Value = 'cell_name';
            app.NoofintervalsLabel = uilabel(app.LeftPanel);
            app.NoofintervalsLabel.Text = 'No. of intervals';
            app.OKButton = uibutton(app.LeftPanel, 'state');
            app.OKButton.ValueChangedFcn = createCallbackFcn(app, @OKButtonValueChanged, true);
            app.OKButton.Text = 'OK';
            app.ConfirmSelectionLabel = uilabel(app.LeftPanel);
            app.ConfirmSelectionLabel.Text = 'Confirm Selection';
            app.OutputinformationLabel = uilabel(app.LeftPanel);
            app.OutputinformationLabel.Text = 'Output information';
            app.TextArea = uitextarea(app.LeftPanel);
            app.IntervalsselectedLabel = uilabel(app.LeftPanel);
            app.IntervalsselectedLabel.Text = 'Intervals selected: ';
            app.RESETButton = uibutton(app.LeftPanel, 'state');
            app.RESETButton.ValueChangedFcn = createCallbackFcn(app, @RESETButtonValueChanged, true);
            app.RESETButton.Text = 'RESET';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.ChannelselectionTab = uitab(app.TabGroup);
            app.ChannelselectionTab.Title = 'Channel selection';
            app.Channel1Label = uilabel(app.ChannelselectionTab);
            app.Channel1Label.Text = '  Channel 1';
            app.FrameSliderLabel = uilabel(app.ChannelselectionTab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.ChannelselectionTab);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.Channel1ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel1ButtonGroup.Title = 'Channel 1';
            app.NoneButton_5 = uiradiobutton(app.Channel1ButtonGroup);
            app.NoneButton_5.Text = 'None';
            app.NoneButton_5.Value = true;
            app.MitochondriaButton = uiradiobutton(app.Channel1ButtonGroup);
            app.MitochondriaButton.Text = 'Mitochondria';
            app.CytoplasmButton = uiradiobutton(app.Channel1ButtonGroup);
            app.CytoplasmButton.Text = 'Cytoplasm';
            app.NucleusButton = uiradiobutton(app.Channel1ButtonGroup);
            app.NucleusButton.Text = 'Nucleus';
            app.Channel2ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel2ButtonGroup.Title = 'Channel 2';
            app.NoneButton_6 = uiradiobutton(app.Channel2ButtonGroup);
            app.NoneButton_6.Text = 'None';
            app.NoneButton_6.Value = true;
            app.MitochondriaButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.MitochondriaButton_2.Text = 'Mitochondria';
            app.CytoplasmButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.CytoplasmButton_2.Text = 'Cytoplasm';
            app.NucleusButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.NucleusButton_2.Text = 'Nucleus';
            app.Channel3ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel3ButtonGroup.Title = 'Channel 3';
            app.NoneButton_7 = uiradiobutton(app.Channel3ButtonGroup);
            app.NoneButton_7.Text = 'None';
            app.NoneButton_7.Value = true;
            app.MitochondriaButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.MitochondriaButton_3.Text = 'Mitochondria';
            app.CytoplasmButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.CytoplasmButton_3.Text = 'Cytoplasm';
            app.NucleusButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.NucleusButton_3.Text = 'Nucleus';
            app.Channel4ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel4ButtonGroup.Title = 'Channel 4';
            app.NoneButton_8 = uiradiobutton(app.Channel4ButtonGroup);
            app.NoneButton_8.Text = 'None';
            app.NoneButton_8.Value = true;
            app.MitochondriaButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.MitochondriaButton_4.Text = 'Mitochondria';
            app.CytoplasmButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.CytoplasmButton_4.Text = 'Cytoplasm';
            app.NucleusButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.NucleusButton_4.Text = 'Nucleus';
            app.AcceptChannelsandCropImageButton = uibutton(app.ChannelselectionTab, 'state');
            app.AcceptChannelsandCropImageButton.ValueChangedFcn = createCallbackFcn(app, @AcceptChannelsandCropImageButtonValueChanged, true);
            app.AcceptChannelsandCropImageButton.Text = 'Accept Channels and Crop Image';
            app.UIAxes = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.AcceptCropButton = uibutton(app.ChannelselectionTab, 'state');
            app.AcceptCropButton.ValueChangedFcn = createCallbackFcn(app, @AcceptCropButtonValueChanged, true);
            app.AcceptCropButton.Text = 'Accept Crop';
            app.Channel2Label = uilabel(app.ChannelselectionTab);
            app.Channel2Label.Text = '  Channel 2';
            app.Channel3Label = uilabel(app.ChannelselectionTab);
            app.Channel3Label.Text = '  Channel 3';
            app.Channel4Label = uilabel(app.ChannelselectionTab);
            app.Channel4Label.Text = '  Channel 4';
            app.CompartmentviewingpercentilesTab = uitab(app.TabGroup);
            app.CompartmentviewingpercentilesTab.Title = 'Compartment viewing percentiles';
            app.LoadNextIterationButton = uibutton(app.CompartmentviewingpercentilesTab, 'state');
            app.LoadNextIterationButton.ValueChangedFcn = createCallbackFcn(app, @LoadNextIterationButtonValueChanged, true);
            app.LoadNextIterationButton.Text = 'Load Next Iteration';
            app.UIAxes7 = uiaxes(app.CompartmentviewingpercentilesTab);
            title(app.UIAxes7, '')
            xlabel(app.UIAxes7, '')
            ylabel(app.UIAxes7, '')
            app.UIAxes7.XTick = [];
            app.UIAxes7.YTick = [];
            app.UIAxes7_2 = uiaxes(app.CompartmentviewingpercentilesTab);
            title(app.UIAxes7_2, '')
            xlabel(app.UIAxes7_2, '')
            ylabel(app.UIAxes7_2, '')
            app.UIAxes7_2.XTick = [];
            app.UIAxes7_2.YTick = [];
            app.PercentageSliderLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.PercentageSliderLabel.Text = 'Percentage';
            app.PercentageSlider = uislider(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider.ValueChangingFcn = createCallbackFcn(app, @PercentageSliderValueChanging, true);
            app.PercentageSlider_2Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider_2Label.Text = 'Percentage';
            app.PercentageSlider_2 = uislider(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider_2.ValueChangingFcn = createCallbackFcn(app, @PercentageSlider_2ValueChanging, true);
            app.NucleusLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.NucleusLabel.Text = 'Nucleus';
            app.MitochondriaLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.MitochondriaLabel.Text = 'Mitochondria';
            app.AcceptPercentilesforImageViewingButton = uibutton(app.CompartmentviewingpercentilesTab, 'state');
            app.AcceptPercentilesforImageViewingButton.ValueChangedFcn = createCallbackFcn(app, @AcceptPercentilesforImageViewingButtonValueChanged, true);
            app.AcceptPercentilesforImageViewingButton.Text = 'Accept Percentiles for Image Viewing';
            app.FramewindowSliderLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.FramewindowSliderLabel.Text = 'Frame window';
            app.FramewindowSlider = uislider(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider.Limits = [1 10];
            app.FramewindowSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.FramewindowSlider.ValueChangingFcn = createCallbackFcn(app, @FramewindowSliderValueChanging, true);
            app.FramewindowSlider.MinorTicks = [];
            app.FramewindowSlider.Value = 1;
            app.FramewindowSlider_2Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider_2Label.Text = 'Frame window';
            app.FramewindowSlider_2 = uislider(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider_2.Limits = [1 10];
            app.FramewindowSlider_2.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.FramewindowSlider_2.ValueChangingFcn = createCallbackFcn(app, @FramewindowSlider_2ValueChanging, true);
            app.FramewindowSlider_2.MinorTicks = [];
            app.FramewindowSlider_2.Value = 1;
            app.UIAxes7_3 = uiaxes(app.CompartmentviewingpercentilesTab);
            title(app.UIAxes7_3, '')
            xlabel(app.UIAxes7_3, '')
            ylabel(app.UIAxes7_3, '')
            app.UIAxes7_3.XTick = [];
            app.UIAxes7_3.YTick = [];
            app.PercentageSlider_3Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider_3Label.Text = 'Percentage';
            app.PercentageSlider_3 = uislider(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider_3.ValueChangingFcn = createCallbackFcn(app, @PercentageSlider_3ValueChanging, true);
            app.FramewindowSlider_3Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider_3Label.Text = 'Frame window';
            app.FramewindowSlider_3 = uislider(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider_3.Limits = [1 10];
            app.FramewindowSlider_3.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.FramewindowSlider_3.ValueChangingFcn = createCallbackFcn(app, @FramewindowSlider_3ValueChanging, true);
            app.FramewindowSlider_3.MinorTicks = [];
            app.FramewindowSlider_3.Value = 1;
            app.AllcompartmentsLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.AllcompartmentsLabel.Text = 'All compartments';
            app.CellboundarycropTab = uitab(app.TabGroup);
            app.CellboundarycropTab.Title = 'Cell boundary crop';
            app.UIAxes5 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes5, '')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.UIAxes6 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6, '')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, '')
            app.UIAxes6.XTick = [];
            app.UIAxes6.YTick = [];
            app.UIAxes6_2 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6_2, '')
            xlabel(app.UIAxes6_2, '')
            ylabel(app.UIAxes6_2, '')
            app.UIAxes6_2.XTick = [];
            app.UIAxes6_2.YTick = [];
            app.UIAxes6_3 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6_3, '')
            xlabel(app.UIAxes6_3, '')
            ylabel(app.UIAxes6_3, '')
            app.UIAxes6_3.XTick = [];
            app.UIAxes6_3.YTick = [];
            app.ImageSelectionButtonGroup = uibuttongroup(app.CellboundarycropTab);
            app.ImageSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageSelectionButtonGroupSelectionChanged, true);
            app.ImageSelectionButtonGroup.Title = 'Image Selection';
            app.NullButton_2 = uitogglebutton(app.ImageSelectionButtonGroup);
            app.NullButton_2.Text = 'Null';
            app.NullButton_2.Value = true;
            app.Image1Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image1Button.Text = 'Image 1';
            app.Image2Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image2Button.Text = 'Image 2';
            app.Image3Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image3Button.Text = 'Image 3';
            app.Image4Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image4Button.Text = 'Image 4';
            app.ConfirmcropButton = uibutton(app.CellboundarycropTab, 'state');
            app.ConfirmcropButton.ValueChangedFcn = createCallbackFcn(app, @ConfirmcropButtonValueChanged, true);
            app.ConfirmcropButton.Text = 'Confirm crop';
            app.UIAxes6_4 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6_4, '')
            xlabel(app.UIAxes6_4, '')
            ylabel(app.UIAxes6_4, '')
            app.UIAxes6_4.XTick = [];
            app.UIAxes6_4.YTick = [];
            app.AllframesLabel = uilabel(app.CellboundarycropTab);
            app.AllframesLabel.Text = 'All frames';
            app.RegionframesLabel = uilabel(app.CellboundarycropTab);
            app.RegionframesLabel.Text = 'Region frames';
            app.MaxintensityallchannelsLabel = uilabel(app.CellboundarycropTab);
            app.MaxintensityallchannelsLabel.Text = {'Max intensity '; '- all channels'};
            app.MaxintensityallchannelsLabel_2 = uilabel(app.CellboundarycropTab);
            app.MaxintensityallchannelsLabel_2.Text = {'Max intensity '; '- all channels'};
            app.MaxintensityproteinchannelLabel = uilabel(app.CellboundarycropTab);
            app.MaxintensityproteinchannelLabel.Text = {'Max intensity '; '- protein channel'};
            app.MaxintensityproteinchannelLabel_2 = uilabel(app.CellboundarycropTab);
            app.MaxintensityproteinchannelLabel_2.Text = {'Max intensity '; '- protein channel'};
            app.RoughcropButton = uibutton(app.CellboundarycropTab, 'push');
            app.RoughcropButton.ButtonPushedFcn = createCallbackFcn(app, @RoughcropButtonPushed, true);
            app.RoughcropButton.Text = 'Rough crop';
            app.CellthresholdingTab = uitab(app.TabGroup);
            app.CellthresholdingTab.Title = 'Cell thresholding';
            app.UIAxes8 = uiaxes(app.CellthresholdingTab);
            title(app.UIAxes8, '')
            xlabel(app.UIAxes8, '')
            ylabel(app.UIAxes8, '')
            app.UIAxes8.XTick = [];
            app.UIAxes8.YTick = [];
            app.UIAxes8_2 = uiaxes(app.CellthresholdingTab);
            title(app.UIAxes8_2, '')
            xlabel(app.UIAxes8_2, '')
            ylabel(app.UIAxes8_2, '')
            app.UIAxes8_2.XTick = [];
            app.UIAxes8_2.YTick = [];
            app.UIAxes8_3 = uiaxes(app.CellthresholdingTab);
            title(app.UIAxes8_3, '')
            xlabel(app.UIAxes8_3, '')
            ylabel(app.UIAxes8_3, '')
            app.UIAxes8_3.XTick = [];
            app.UIAxes8_3.YTick = [];
            app.Thresholdscale01SliderLabel = uilabel(app.CellthresholdingTab);
            app.Thresholdscale01SliderLabel.Text = 'Threshold scale 0.1';
            app.Thresholdscale01Slider = uislider(app.CellthresholdingTab);
            app.Thresholdscale01Slider.Limits = [0 0.9];
            app.Thresholdscale01Slider.MajorTicks = [0 0.3 0.6 0.9];
            app.Thresholdscale01Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdscale01SliderValueChanged, true);
            app.Thresholdscale01Slider.MinorTicks = [0.1 0.2 0.3 0.4 0.5 0.7 0.8];
            app.Thresholdscale001SliderLabel = uilabel(app.CellthresholdingTab);
            app.Thresholdscale001SliderLabel.Text = 'Threshold scale 0.01';
            app.Thresholdscale001Slider = uislider(app.CellthresholdingTab);
            app.Thresholdscale001Slider.Limits = [0 0.09];
            app.Thresholdscale001Slider.MajorTicks = [0 0.03 0.06 0.09];
            app.Thresholdscale001Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdscale001SliderValueChanged, true);
            app.Thresholdscale001Slider.MinorTicks = [0.01 0.02 0.04 0.05 0.07 0.08];
            app.Thresholdscale0001SliderLabel = uilabel(app.CellthresholdingTab);
            app.Thresholdscale0001SliderLabel.Text = 'Threshold scale 0.001';
            app.Thresholdscale0001Slider = uislider(app.CellthresholdingTab);
            app.Thresholdscale0001Slider.Limits = [0 0.009];
            app.Thresholdscale0001Slider.MajorTicks = [0 0.003 0.006 0.009];
            app.Thresholdscale0001Slider.MajorTickLabels = {'0', '0.003', '0.006', '0.009', ''};
            app.Thresholdscale0001Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdscale0001SliderValueChanged, true);
            app.Thresholdscale0001Slider.MinorTicks = [0.001 0.002 0.004 0.005 0.007 0.008];
            app.Thresholdscale00001SliderLabel = uilabel(app.CellthresholdingTab);
            app.Thresholdscale00001SliderLabel.Text = 'Threshold scale 0.0001';
            app.Thresholdscale00001Slider = uislider(app.CellthresholdingTab);
            app.Thresholdscale00001Slider.Limits = [0 0.0009];
            app.Thresholdscale00001Slider.MajorTicks = [0 0.0003 0.0006 0.0009];
            app.Thresholdscale00001Slider.MajorTickLabels = {'0', '0.0003', '0.0006', '0.0009', ''};
            app.Thresholdscale00001Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdscale00001SliderValueChanged, true);
            app.Thresholdscale00001Slider.MinorTicks = [0.0001 0.0002 0.0004 0.0005 0.0007 0.0008];
            app.Thresholdvalue000000Label = uilabel(app.CellthresholdingTab);
            app.Thresholdvalue000000Label.Text = {'Threshold value:'; '0.00000'};
            app.AcceptthresholdButton = uibutton(app.CellthresholdingTab, 'push');
            app.AcceptthresholdButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptthresholdButtonPushed, true);
            app.AcceptthresholdButton.Text = 'Accept threshold';
            app.CompartmentthresholdingTab = uitab(app.TabGroup);
            app.CompartmentthresholdingTab.Title = 'Compartment thresholding';
            app.UIAxes9 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes9, '')
            xlabel(app.UIAxes9, '')
            ylabel(app.UIAxes9, '')
            app.UIAxes9.XTick = [];
            app.UIAxes9.YTick = [];
            app.UIAxes10 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10, '')
            xlabel(app.UIAxes10, '')
            ylabel(app.UIAxes10, '')
            app.UIAxes10.XTick = [];
            app.UIAxes10.YTick = [];
            app.UIAxes10_2 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_2, '')
            xlabel(app.UIAxes10_2, '')
            ylabel(app.UIAxes10_2, '')
            app.UIAxes10_2.XTick = [];
            app.UIAxes10_2.YTick = [];
            app.UIAxes10_3 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_3, '')
            xlabel(app.UIAxes10_3, '')
            ylabel(app.UIAxes10_3, '')
            app.UIAxes10_3.XTick = [];
            app.UIAxes10_3.YTick = [];
            app.UIAxes10_4 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_4, '')
            xlabel(app.UIAxes10_4, '')
            ylabel(app.UIAxes10_4, '')
            app.UIAxes10_4.XTick = [];
            app.UIAxes10_4.YTick = [];
            app.UIAxes10_5 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_5, '')
            xlabel(app.UIAxes10_5, '')
            ylabel(app.UIAxes10_5, '')
            app.UIAxes10_5.XTick = [];
            app.UIAxes10_5.YTick = [];
            app.UIAxes10_6 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_6, '')
            xlabel(app.UIAxes10_6, '')
            ylabel(app.UIAxes10_6, '')
            app.UIAxes10_6.XTick = [];
            app.UIAxes10_6.YTick = [];
            app.Mitochondriathresholdscale01SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale01SliderLabel.Text = {'Mitochondria'; 'threshold scale 0.1'};
            app.Mitochondriathresholdscale01Slider = uislider(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale01Slider.Limits = [0 0.9];
            app.Mitochondriathresholdscale01Slider.MajorTicks = [0 0.3 0.6 0.9];
            app.Mitochondriathresholdscale01Slider.ValueChangedFcn = createCallbackFcn(app, @Mitochondriathresholdscale01SliderValueChanged, true);
            app.Mitochondriathresholdscale01Slider.MinorTicks = [0.1 0.2 0.4 0.5 0.7 0.8];
            app.Mitochondriathresholdscale001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale001SliderLabel.Text = {'Mitochondria '; 'threshold scale 0.01'};
            app.Mitochondriathresholdscale001Slider = uislider(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale001Slider.Limits = [0 0.09];
            app.Mitochondriathresholdscale001Slider.MajorTicks = [0 0.03 0.06 0.09];
            app.Mitochondriathresholdscale001Slider.ValueChangedFcn = createCallbackFcn(app, @Mitochondriathresholdscale001SliderValueChanged, true);
            app.Mitochondriathresholdscale001Slider.MinorTicks = [0.01 0.02 0.04 0.05 0.07 0.08];
            app.Mitochondriathresholdscale0001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale0001SliderLabel.Text = {'Mitochondria'; 'threshold scale 0.001'};
            app.Mitochondriathresholdscale0001Slider = uislider(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale0001Slider.Limits = [0 0.009];
            app.Mitochondriathresholdscale0001Slider.MajorTicks = [0 0.003 0.006 0.009];
            app.Mitochondriathresholdscale0001Slider.ValueChangedFcn = createCallbackFcn(app, @Mitochondriathresholdscale0001SliderValueChanged, true);
            app.Mitochondriathresholdscale0001Slider.MinorTicks = [0.001 0.002 0.004 0.005 0.007 0.008];
            app.Mitochondriathresholdscale00001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale00001SliderLabel.Text = {'Mitochondria'; 'threshold scale 0.0001'};
            app.Mitochondriathresholdscale00001Slider = uislider(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale00001Slider.Limits = [0 0.0009];
            app.Mitochondriathresholdscale00001Slider.MajorTicks = [0 0.0003 0.0006 0.0009];
            app.Mitochondriathresholdscale00001Slider.ValueChangedFcn = createCallbackFcn(app, @Mitochondriathresholdscale00001SliderValueChanged, true);
            app.Mitochondriathresholdscale00001Slider.MinorTicks = [0.0001 0.0002 0.0004 0.0005 0.0007 0.0008];
            app.Nucleusthresholdscale01SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale01SliderLabel.Text = {'Nucleus'; 'threshold scale 0.1'};
            app.Nucleusthresholdscale01Slider = uislider(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale01Slider.Limits = [0 0.9];
            app.Nucleusthresholdscale01Slider.MajorTicks = [0 0.3 0.6 0.9];
            app.Nucleusthresholdscale01Slider.ValueChangedFcn = createCallbackFcn(app, @Nucleusthresholdscale01SliderValueChanged, true);
            app.Nucleusthresholdscale01Slider.MinorTicks = [0.1 0.2 0.4 0.5 0.7 0.8];
            app.Nucleusthresholdscale001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale001SliderLabel.Text = {'Nucleus'; 'threshold scale 0.01'};
            app.Nucleusthresholdscale001Slider = uislider(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale001Slider.Limits = [0 0.09];
            app.Nucleusthresholdscale001Slider.MajorTicks = [0 0.03 0.06 0.09];
            app.Nucleusthresholdscale001Slider.ValueChangedFcn = createCallbackFcn(app, @Nucleusthresholdscale001SliderValueChanged, true);
            app.Nucleusthresholdscale001Slider.MinorTicks = [0.01 0.02 0.04 0.05 0.07 0.08];
            app.Nucleusthresholdscale0001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale0001SliderLabel.Text = {'Nucleus'; 'threshold scale 0.001'};
            app.Nucleusthresholdscale0001Slider = uislider(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale0001Slider.Limits = [0 0.009];
            app.Nucleusthresholdscale0001Slider.MajorTicks = [0 0.003 0.006 0.009];
            app.Nucleusthresholdscale0001Slider.ValueChangedFcn = createCallbackFcn(app, @Nucleusthresholdscale0001SliderValueChanged, true);
            app.Nucleusthresholdscale0001Slider.MinorTicks = [0.001 0.002 0.004 0.005 0.007 0.008];
            app.Nucleusthresholdscale00001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale00001SliderLabel.Text = {'Nucleus '; 'threshold scale 0.0001'};
            app.Nucleusthresholdscale00001Slider = uislider(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale00001Slider.Limits = [0 0.0009];
            app.Nucleusthresholdscale00001Slider.MajorTicks = [0 0.0003 0.0006 0.0009];
            app.Nucleusthresholdscale00001Slider.ValueChangedFcn = createCallbackFcn(app, @Nucleusthresholdscale00001SliderValueChanged, true);
            app.Nucleusthresholdscale00001Slider.MinorTicks = [0.0001 0.0002 0.0004 0.0005 0.0007 0.0008];
            app.Mitochondrialthresholdvalue000000Label = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondrialthresholdvalue000000Label.Text = {'Mitochondrial threshold value: 0.00000'; 'Nucleus threshold value: 0.00000'};
            app.AcceptthresholdsButton = uibutton(app.CompartmentthresholdingTab, 'push');
            app.AcceptthresholdsButton.Text = 'Accept thresholds';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = opto_analyser_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end